classdef CONTROL_TESTING_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        VariableTuner       simulink.ui.control.VariableTuner
        TimeScope4          matlab.ui.scope.TimeScope
        TimeScope3          matlab.ui.scope.TimeScope
        SimulationProgress  simulink.ui.control.SimulationProgress
        Knob                matlab.ui.control.Knob
        SimulationControls  simulink.ui.control.SimulationControls
        Tab2                matlab.ui.container.Tab
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.SimulationControls = uisimcontrols(app.Tab);
            app.SimulationControls.Simulation = app.Simulation;
            app.Knob = uiknob(app.Tab, 'continuous');
            app.Knob.Limits = [0 2];
            app.Knob.MajorTicks = [0 1 2 3 4 5];
            app.Knob.MinorTicks = [0 1 2 3 4 5 6];
            app.Knob.Value = 1;
            app.SimulationProgress = uisimprogress(app.Tab);
            app.SimulationProgress.Simulation = app.Simulation;
            app.TimeScope3 = uitimescope(app.Tab);
            app.TimeScope3.LegendNames = {'Old_Value:1'};
            app.TimeScope3.Title = 'Old_Value:1';
            app.TimeScope3.YLabel = 'Amplitude';
            app.TimeScope4 = uitimescope(app.Tab);
            app.TimeScope4.LegendNames = {'Spring Dampping/Signal Specification:1'};
            app.TimeScope4.Title = 'Spring Dampping/Signal Specification:1';
            app.TimeScope4.YLabel = 'Amplitude';
            app.VariableTuner = uisimvartuner(app.Tab);
            app.VariableTuner.Simulation = app.Simulation;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Knob, 'Value', app.Simulation.TunableVariables, 'C');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Old_Value:1'], app.TimeScope3);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Spring Dampping/Signal Specification:1'], app.TimeScope4);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CONTROL_TESTING_APP

            % Associate the Simulink Model
            app.Simulation = simulation('CONTROL_TESTING');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end